classdef cellmasker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SmoothnessEditFieldLabel      matlab.ui.control.Label
        SmoothnessEditField           matlab.ui.control.NumericEditField
        FindCellsParametersLabel      matlab.ui.control.Label
        MaxLengthpixEditFieldLabel    matlab.ui.control.Label
        MaxLengthpixEditField         matlab.ui.control.NumericEditField
        MinLengthpixEditFieldLabel    matlab.ui.control.Label
        MinLengthpixEditField         matlab.ui.control.NumericEditField
        BrightnessThresholdEditFieldLabel  matlab.ui.control.Label
        BrightnessThresholdEditField  matlab.ui.control.NumericEditField
        FindCellsButton               matlab.ui.control.Button
        DeleteCurrentCell             matlab.ui.control.Button
        ApproveCurrentCell            matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        CenterPanel                   matlab.ui.container.Panel
        MainImageAxes                 matlab.ui.control.UIAxes
        ApprovedImageAxes             matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        ApprovedCellListListBoxLabel  matlab.ui.control.Label
        ApprovedCellListListBox       matlab.ui.control.ListBox
        DeleteButton_2                matlab.ui.control.Button
        AddNewCellButton              matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Image = []; % main image
        Mask = [];  % cell mask (1 => cells)
        FoundCellList = {};     % list generated by `findcells`
        ApprovedCellList = [];  % list of approved cells
    end
    
    methods (Access = private)
        
        function drawImage(app, plot_boundary)
            if nargin == 1 
                plot_boundary = true;
            end
            % show image and mask boundaries
            imagesc (app.MainImageAxes, img);
            colormap (app.MainImageAxes, "gray");
            axis (app.MainImageAxes, ...
                horzcat (1, size (img, 1), 1, size (img, 2)));
            if plot_boundary
                % show mask boundaries
                B = bwboundaries (mask);
                hold (app.MainImageAxes, "on")
                for ib = 1:numel(B)
                    bound = B{ib};
                    plot (app.MainImageAxes, bound(:,2), bound(:,1), 'b', ...
                        'LineWidth', 1.5)
                end
                hold (app.MainImageAxes, "off")
            end
        end
        
        function clearApprovedCells(app)
            app.ApprovedCellList = [];
            imagesc (app.ApprovedImageAxes, app.Image);
            colormap (app.ApprovedImageAxes, "gray")
            axis (app.ApprovedImageAxes, ...
                horzcat (1, size(app.Image,1), 1, size(app.Image,2)));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img, mask)
            
            % input validation
            assert (length (size (img)) == 2, 'img must be 2d array')
            assert (all (size (img) == size (mask)), ...
                'input image and mask must be same size')
            if not (isdouble (img))
                img = im2double (img);
            end
            if not (islogical (mask))
               mask = not (mask == 0);
            end
            app.Image = img;
            app.Mask = mask;
            % draw image
            app.drawImage (true);
            
        end

        % Button pushed function: FindCellsButton
        function FindCellsButtonPushed(app, event)
            % make sure user wants to proceed...
            if not (isempty (app.ApprovedCellList))
                answer = questdlg ("Re-finding cells will clear approved cells. Proceed?", ...
                    "Restart approval?", "yes", "no", "no");
            end
            if strcmp (answer, "no") % they don't
                return
            end
            % if we got here, we're good to go
            app.clearApprovedCells();
            
            % run findcells and plot answer
            cellz = findcells (app.Image, not (app.Mask), ...
                app.SmoothnessEditField.Value, ...
                app.MaxLengthpixEditField.Value, ...
                app.MinLengthpixEditField.Value, ...
                app.BrightnessThresholdEditField.Value);
            hold (app.MainImageAxes, "on")
            for ic = 1:numel(cellz)
                plot (app.MainImageAxes, cellz(ic).x, cellz(ic).y)
            end
            hold (app.MainImageAxes, "off")
            
            % initialize "current cell"
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {544, 544, 544};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {544, 544};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 280};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SmoothnessEditFieldLabel = uilabel(app.LeftPanel);
            app.SmoothnessEditFieldLabel.Text = 'Smoothness';
            app.SmoothnessEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SmoothnessEditField.Value = 2;
            app.FindCellsParametersLabel = uilabel(app.LeftPanel);
            app.FindCellsParametersLabel.Text = 'FindCells Parameters';
            app.MaxLengthpixEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxLengthpixEditFieldLabel.Text = 'Max. Length (pix)';
            app.MaxLengthpixEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxLengthpixEditField.Value = 80;
            app.MinLengthpixEditFieldLabel = uilabel(app.LeftPanel);
            app.MinLengthpixEditFieldLabel.Text = 'Min. Length (pix)';
            app.MinLengthpixEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinLengthpixEditField.Value = 10;
            app.BrightnessThresholdEditFieldLabel = uilabel(app.LeftPanel);
            app.BrightnessThresholdEditFieldLabel.Text = 'Brightness Threshold';
            app.BrightnessThresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BrightnessThresholdEditField.Value = 0.3;
            app.FindCellsButton = uibutton(app.LeftPanel, 'push');
            app.FindCellsButton.ButtonPushedFcn = createCallbackFcn(app, @FindCellsButtonPushed, true);
            app.FindCellsButton.Text = 'Find Cells';
            app.DeleteCurrentCell = uibutton(app.LeftPanel, 'push');
            app.DeleteCurrentCell.Text = 'Delete';
            app.ApproveCurrentCell = uibutton(app.LeftPanel, 'push');
            app.ApproveCurrentCell.Text = 'Approve';
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Current Cell')
            app.CenterPanel = uipanel(app.GridLayout);
            app.MainImageAxes = uiaxes(app.CenterPanel);
            title(app.MainImageAxes, 'Original Image')
            app.MainImageAxes.XTick = [];
            app.MainImageAxes.YTick = [];
            app.ApprovedImageAxes = uiaxes(app.CenterPanel);
            title(app.ApprovedImageAxes, 'Approved Cells')
            app.ApprovedImageAxes.XTick = [];
            app.ApprovedImageAxes.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.ApprovedCellListListBoxLabel = uilabel(app.RightPanel);
            app.ApprovedCellListListBoxLabel.Text = 'Approved Cell List';
            app.ApprovedCellListListBox = uilistbox(app.RightPanel);
            app.DeleteButton_2 = uibutton(app.RightPanel, 'push');
            app.DeleteButton_2.Text = 'Delete';
            app.AddNewCellButton = uibutton(app.RightPanel, 'push');
            app.AddNewCellButton.Text = 'Add New Cell';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellmasker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end